classdef test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_C       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_MULTI   matlab.ui.control.Button
        Button_MINUS   matlab.ui.control.Button
        Button_PLUS    matlab.ui.control.Button
        Eutton_EQUAL   matlab.ui.control.Button
        Button_0       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_DOT     matlab.ui.control.Button
        Button_1       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_DEVIDE  matlab.ui.control.Button
        Button_9       matlab.ui.control.Button
        Button_8       matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
        EditField      matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function addValue(app, value)
            % value is string
            display = app.EditField.Value;
            charDisplay = convertStringsToChars(display);
            lastDisplay = charDisplay(strlength(charDisplay));
            
            if (strlength(charDisplay) == 1) && display == "0"
                app.EditField.Value = value;
                return;
            end
         
            switch(lastDisplay)
                case '+'
                    display = display + value;
                case '-'
                    display = display + value;
                case 'X'
                    display = display + value;
                case '/'
                    display = display + value;
                case '0'
                    if (charDisplay(strlength(charDisplay)-1) >= '0') && (charDisplay(strlength(charDisplay)-1) <= '9') 
                        display = display + value;
                    end
                otherwise
                    display = display + value;
            end
                
            app.EditField.Value = display;
        end
        
        
        function [value, action] = actionScan(app)
            % return: 1(action), 0(no action)
            charDisplay = convertStringsToChars(app.EditField.Value);
            value = 0;
            action = "";
            
            for ii = strlength(charDisplay):-1:2   % first number could be negative
                if (charDisplay(ii) == '+') || (charDisplay(ii) == '-') || (charDisplay(ii) == '*') || (charDisplay(ii) == '/')
                    value = 1;
                    for jj = ii:strlength(charDisplay)
                        action = action + charDisplay(jj);
                    end
                    return;
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            addValue(app, "0");
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            addValue(app, "1");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            addValue(app, "2");
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            addValue(app, "3");
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            addValue(app, "4");
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            addValue(app, "5");
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            addValue(app, "6");
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            addValue(app, "7");
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            addValue(app, "8");
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            addValue(app, "9");
        end

        % Button pushed function: Button_DOT
        function Button_DOTPushed(app, event)
            app.EditField.Value = app.EditField.Value + ".";
        end

        % Button pushed function: Button_PLUS
        function Button_PLUSPushed(app, event)
            app.EditField.Value = app.EditField.Value + "+";
        end

        % Button pushed function: Button_MINUS
        function Button_MINUSPushed(app, event)
            app.EditField.Value = app.EditField.Value + "-";
        end

        % Button pushed function: Button_MULTI
        function Button_MULTIPushed(app, event)
            app.EditField.Value = app.EditField.Value + "*";
        end

        % Button pushed function: Button_DEVIDE
        function Button_DEVIDEPushed(app, event)
            app.EditField.Value = app.EditField.Value + "/";
        end

        % Button pushed function: Eutton_EQUAL
        function Eutton_EQUALButtonPushed(app, event)
            persistent preAction;
            if isempty(preAction)
                preAction = "";
            end
            
            [value, action] = actionScan(app);
            if value == 0
                app.EditField.Value = app.EditField.Value + preAction;
            else
                preAction = action;
            end
            
            app.EditField.Value = num2str(eval(char(app.EditField.Value)));
        end

        % Button pushed function: Button_C
        function Button_CPushed(app, event)
            app.EditField.Value = "0";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '0';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_DEVIDE = uibutton(app.UIFigure, 'push');
            app.Button_DEVIDE.ButtonPushedFcn = createCallbackFcn(app, @Button_DEVIDEPushed, true);
            app.Button_DEVIDE.Text = '/';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_DOT = uibutton(app.UIFigure, 'push');
            app.Button_DOT.ButtonPushedFcn = createCallbackFcn(app, @Button_DOTPushed, true);
            app.Button_DOT.Text = '.';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_0 = uibutton(app.UIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.Eutton_EQUAL = uibutton(app.UIFigure, 'push');
            app.Eutton_EQUAL.ButtonPushedFcn = createCallbackFcn(app, @Eutton_EQUALButtonPushed, true);
            app.Eutton_EQUAL.Text = '=';
            app.Button_PLUS = uibutton(app.UIFigure, 'push');
            app.Button_PLUS.ButtonPushedFcn = createCallbackFcn(app, @Button_PLUSPushed, true);
            app.Button_PLUS.Text = '+';
            app.Button_MINUS = uibutton(app.UIFigure, 'push');
            app.Button_MINUS.ButtonPushedFcn = createCallbackFcn(app, @Button_MINUSPushed, true);
            app.Button_MINUS.Text = '-';
            app.Button_MULTI = uibutton(app.UIFigure, 'push');
            app.Button_MULTI.ButtonPushedFcn = createCallbackFcn(app, @Button_MULTIPushed, true);
            app.Button_MULTI.Text = 'X';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_C = uibutton(app.UIFigure, 'push');
            app.Button_C.ButtonPushedFcn = createCallbackFcn(app, @Button_CPushed, true);
            app.Button_C.Text = 'C';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end